classdef PAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AHMEDRAYHANAUSTEEE45Label      matlab.ui.control.Label
        ModulationIndexKnob            matlab.ui.control.Knob
        ModulationIndexKnobLabel       matlab.ui.control.Label
        WaveTypeSwitch                 matlab.ui.control.Switch
        WaveTypeSwitchLabel            matlab.ui.control.Label
        SNRKnob                        matlab.ui.control.Knob
        SNRKnobLabel                   matlab.ui.control.Label
        PulseAmplitudeModulationLabel  matlab.ui.control.Label
        ReceivingendLabel              matlab.ui.control.Label
        TransmissionEndLabel           matlab.ui.control.Label
        AEF                            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel        matlab.ui.control.Label
        FEF                            matlab.ui.control.NumericEditField
        FrequencyLabel                 matlab.ui.control.Label
        EvaluateButton                 matlab.ui.control.Button
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            samples = 1000;
            fm = app.FEF.Value;   %number of cycles - user
            Am = app.AEF.Value;        %Amplitude - user
            t = linspace(0,1,samples);
            
            if(app.WaveTypeSwitch.Value=="Sin")
                m_t = Am*sin(2*pi*fm*t);            %message signal
            elseif (app.WaveTypeSwitch.Value=="Cos")
                m_t = Am*cos(2*pi*fm*t);      
            end         
            %Ahmed Rayhan
            %AUST EEE-45
            %Adding noise 
            SNR = app.SNRKnob.Value;             % Signal-to-Noise Ratio in dB -user
            noise = randn(1, samples);          % Generate Gaussian noise
            noise_power = 10^(-SNR/10);         % Convert SNR from dB to linear scale
            noise = noise * sqrt(noise_power);  % Scale the noise power
            ns = m_t + noise;                   % Add noise to the message signal
            
            %signal_SNR=snr(m_t, ns)
            
            % Carrier Signal
            modulation_index = app.ModulationIndexKnob.Value;
            c_t = zeros(1,samples);
            for i = 1:20:samples
                c_t(1, i:i+9) = modulation_index;
                c_t(1, i+10:i+19) = 0;
            end
            
            s_t = ns.*c_t;                      %Modulated signal
            
            
            %demodulation
            dt = s_t.*c_t;
            [b,a] = butter(5,0.01);
            retrieved = filter(b,a,dt);
            
            amplification_factor = 3;
            amplified_signal = retrieved * amplification_factor;
            
            %figure(1)
            plot(app.UIAxes1,t,m_t);
            
            %figure(2);
            plot(app.UIAxes2,t,ns);
            
            %figure(3)
            plot(app.UIAxes3,t,c_t);
            
            %figure(4)
            plot(app.UIAxes4,t,s_t);
            
            %figure(5)
            plot(app.UIAxes5, t, retrieved, 'r', t,amplified_signal, 'g');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Message Signal')
            xlabel(app.UIAxes1, 'Time')
            ylabel(app.UIAxes1, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Message Signal With Noise')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pulse Train Signal')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Pulse Amplitude Modulated Signal')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Amplitude')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Demodulated Signal')
            xlabel(app.UIAxes5, 'Time')
            ylabel(app.UIAxes5, 'Amplitude')
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.FEF = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AEF = uieditfield(app.UIFigure, 'numeric');
            app.TransmissionEndLabel = uilabel(app.UIFigure);
            app.TransmissionEndLabel.Text = 'Transmission End';
            app.ReceivingendLabel = uilabel(app.UIFigure);
            app.ReceivingendLabel.Text = 'Receiving end';
            app.PulseAmplitudeModulationLabel = uilabel(app.UIFigure);
            app.PulseAmplitudeModulationLabel.Text = 'Pulse Amplitude Modulation';
            app.SNRKnobLabel = uilabel(app.UIFigure);
            app.SNRKnobLabel.Text = 'SNR';
            app.SNRKnob = uiknob(app.UIFigure, 'continuous');
            app.SNRKnob.Limits = [0 50];
            app.WaveTypeSwitchLabel = uilabel(app.UIFigure);
            app.WaveTypeSwitchLabel.Text = 'Wave Type';
            app.WaveTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.WaveTypeSwitch.Items = {'Sin', 'Cos'};
            app.WaveTypeSwitch.Value = 'Sin';
            app.ModulationIndexKnobLabel = uilabel(app.UIFigure);
            app.ModulationIndexKnobLabel.Text = 'Modulation Index';
            app.ModulationIndexKnob = uiknob(app.UIFigure, 'continuous');
            app.ModulationIndexKnob.Limits = [0 1];
            app.AHMEDRAYHANAUSTEEE45Label = uilabel(app.UIFigure);
            app.AHMEDRAYHANAUSTEEE45Label.Text = {'AHMED RAYHAN'; 'AUST EEE-45'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end